classdef hazegenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HazeGeneratorUIFigure          matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        FolderSelectToggle             matlab.ui.container.toolbar.PushTool
        PlayVideoToggle                matlab.ui.container.toolbar.ToggleTool
        AdvancedModeToggle             matlab.ui.container.toolbar.ToggleTool
        ToggleTool                     matlab.ui.container.toolbar.ToggleTool
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        Button                         matlab.ui.control.Button
        VisibleDistanceEditField       matlab.ui.control.NumericEditField
        DebugButton                    matlab.ui.control.StateButton
        DoubleMaskButton               matlab.ui.control.StateButton
        VisibleDistanceEditFieldLabel  matlab.ui.control.Label
        AirlightButton                 matlab.ui.control.Button
        AirlightColourLabel            matlab.ui.control.Label
        DepthMapLabel                  matlab.ui.control.Label
        DepthMapType                   matlab.ui.control.DropDown
        SkyMaskLabel                   matlab.ui.control.Label
        SkyMask                        matlab.ui.control.DropDown
        GridLayout6                    matlab.ui.container.GridLayout
        PreviewDropDownLabel           matlab.ui.control.Label
        PreviewDropDown                matlab.ui.control.DropDown
        SequenceDropDownLabel          matlab.ui.control.Label
        SequenceDropDown               matlab.ui.control.DropDown
        FrameSpinnerLabel              matlab.ui.control.Label
        FrameSpinner                   matlab.ui.control.Spinner
        GridLayout3                    matlab.ui.container.GridLayout
        PreviewImage                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        kittiPath                       % Path to KITTI Files
        previewPath                     % Path to current preview folder
%         previewBuildRequired            % Whether a preview requires construction
%         builtPreview                    % A constructed preview
        sequencePaths                   % A map containing sequence paths
    end
    
    methods (Access = private)
        
        function createVideo(app)
            outpath = fullfile("tests/outputs/dataset/");
            vidwrite = VideoWriter(fullfile(outpath,app.SequenceDropDown.Value),"MPEG-4");
            vidwrite.FrameRate = 10;
            vidwrite.Quality = 100;
            open(vidwrite);
            num = app.FrameSpinner.Limits(2)-1;
            for k = 1:num
                framePath = num2str(k,'%010.f');
                inp = imread(fullfile(app.previewPath,"image_02","data", framePath+".png"));
                inp = im2double(inp);
                depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                disparity = app.createDisparity(depthPath,framePath);
                assignin('base',"disparity",disparity);
                t = app.createTransmission(disparity, framePath);
                frame = app.createOutputPreview(inp,t);
                writeVideo(vidwrite,frame);
            end
            close(vidwrite);
        end
        
        function t = createTransmission(app, disparity, framePath)
%             if app.DebugButton.Value
%                 imgPath = fullfile(app.previewPath,"image_02","data", framePath+".png");
%                 img = imread(imgPath);
%                 img = rgb2gray(im2double(img));
%                 se = strel('square',5);
%                 depthMap = imdilate(depthMap,se);
%                 depthMap = imguidedfilter(depthMap, img, 'NeighborhoodSize',[5 5]);
%             end
            depthMap = 1.0./(disparity.*1000);
            %assignin('base','tdepth',depthMap);
            beta = 3.912/app.VisibleDistanceEditField.Value;
            beta = repelem(beta,3);
            beta = reshape(beta,[1 1 3]);
            t = exp(-beta.*depthMap);
            if app.SkyMask.Value ~= "none" && app.DoubleMaskButton.Value
                maskPath = fullfile(app.previewPath,"sky_mask",app.SkyMask.Value,"img",framePath+".png");
                skyMask = imread(maskPath);
                skyMask = 1.0 - im2double(skyMask);
                t = t.*skyMask;
            end
            
            if app.DebugButton.Value
%                 se = strel('square',5);
%                 t = imerode(t,se);
%                 imgPath = fullfile(app.previewPath,"image_02","data", framePath+".png");
%                 img = imread(imgPath);
%                 img = rgb2gray(im2double(img));
%                 t = imguidedfilter(t, img, 'NeighborhoodSize',[5 5]);
            end
            
%             assignin('base','t',t);
        end
        
        function preview = createOutputPreview(app, inp, t)
            A = reshape(app.AirlightButton.BackgroundColor, [1 1 3]);
            preview = im2uint8(inp.*t+A.*(1.0-t));
        end

        
        function disparity = createDisparity(app, depthPath, framePath)
            depthMap = load(depthPath).depth;
            assignin('base','depthMap',depthMap);
            if app.DepthMapType.Value == "leastereo"
                depthMap = depthMap(10:end,7:end);
                depthMap = depthMap .* 1000;
            elseif app.DepthMapType.Value == "unguided/manydepth"
                depthMap = depthMap .* 6;
            end
            
                    
            disparity = 1.0./depthMap;


            if app.DebugButton.Value
                %imgPath = fullfile(app.previewPath,"image_02","data", framePath+".png");
                %img = imread(imgPath);
                %img = rgb2gray(im2double(img));
                
                
                se = strel('square',7);
                d2 = imdilate(depthMap,se);
                
                %[dx,dy] = imgradientxy(depthMap);
                %depthMap = imguidedfilter(depthMap, img, 'NeighborhoodSize',[5 5]);
                
                dilations = zeros([size(depthMap) 5]);
                sizes = [3, 5, 7, 9, 11];
                for i = 1:length(sizes)
                    se = strel('square',sizes(i));
                    dilations(:,:,i) = imdilate(depthMap,se);
                end
                
                disparity = 1.0./d2;
                
                dmin = min(disparity,[],'all');
                dmax = max(disparity,[],'all');
                spaces = linspace(dmin,dmax,6);
                
                % This shouldn't need to have the temp variables line with something like = dilations(disparity<spaces(1),1), what the hell matlab.
                
                tempBecauseScrewMatlabIndexing = dilations(:,:,1);
                depthMap(disparity<spaces(2)) = tempBecauseScrewMatlabIndexing(disparity<spaces(2));
                tempBecauseScrewMatlabIndexing = dilations(:,:,2);
                depthMap((spaces(2)<=disparity) & (disparity<spaces(3))) = tempBecauseScrewMatlabIndexing((spaces(2)<=disparity) & (disparity<spaces(3)));
                tempBecauseScrewMatlabIndexing = dilations(:,:,3);
                depthMap((spaces(3)<=disparity) & (disparity<spaces(4))) = tempBecauseScrewMatlabIndexing((spaces(3)<=disparity) & (disparity<spaces(4)));
                tempBecauseScrewMatlabIndexing = dilations(:,:,4);
                depthMap((spaces(3)<=disparity) & (disparity<spaces(5))) = tempBecauseScrewMatlabIndexing((spaces(3)<=disparity) & (disparity<spaces(5)));
                tempBecauseScrewMatlabIndexing = dilations(:,:,5);
                depthMap(spaces(5)<=disparity) = tempBecauseScrewMatlabIndexing(spaces(5)<=disparity);
                
                disparity = 1.0./depthMap;
            end
             
            if app.SkyMask.Value ~= "none"
                maskPath = fullfile(app.previewPath,"sky_mask",app.SkyMask.Value,"img",framePath+".png");
                skyMask = imread(maskPath);
                skyMask = 1.0 - im2double(skyMask);
                disparity = skyMask.*disparity;
            end
        end
        
        function preview = createDepthPreview(app, depthPath, framePath)
            disp = app.createDisparity(depthPath, framePath);
            preview = magmacolormap(1.0-disp);
        end
        
        function selectKittiPath(app)
            tempPath = uigetdir(app.kittiPath,'Select the location of the KITTI "train" folder');
            drawnow;
            figure(app.HazeGeneratorUIFigure);
%             disp(app.kittiPath)
            if tempPath~=0
                app.kittiPath = tempPath;
                app.updateKittiPath();
            end
        end
        
        function updateKittiPath(app)
            folders = dir(app.kittiPath);
            app.sequencePaths = containers.Map('KeyType','char','ValueType','char');
            for i = 3:length(folders)
                folderName = folders(i).name;
                sequence = dir(fullfile(app.kittiPath,folderName));
                sequence = sequence([sequence.isdir]);
            %     names = names(3:end);
                for j = 3:length(sequence)
                    app.sequencePaths(sequence(j).name)=fullfile(sequence(j).folder,sequence(j).name);
                end
            end
            app.SequenceDropDown.Items = app.sequencePaths.keys;
%             assignin('base','testNames',names);
%             assignin('base','test',app.SequenceDropDown.Items);
            app.updatePreviewFolder();
        end
        
        function updatePreviewFolder(app)
            %disp(app.SequenceDropDown.Value);
            %disp(app.sequencePaths(app.SequenceDropDown.Value));
            app.previewPath = fullfile(app.sequencePaths(app.SequenceDropDown.Value));
            numImg = numel(dir(fullfile(app.previewPath,"image_02","data",'*.png')));
            app.FrameSpinner.Value = 0;
            app.FrameSpinner.Limits = [-1 numImg];
            app.updatePreview();
        end
        
%         function constructPreview(app)
%             depthPath = fullfile("depth","unguided",app.DepthMapType.Value,"mat");
%             framePath = [num2str(app.FrameSpinner.Value,'%010.f') '.png'];
%         end

        function updatePreview(app)
            framePath = num2str(app.FrameSpinner.Value,'%010.f');
            switch app.PreviewDropDown.Value
                case "Output"
                    inp = imread(fullfile(app.previewPath,"image_02","data", framePath+".png"));
                    inp = im2double(inp);
                    depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                    disparity = app.createDisparity(depthPath,framePath);
                    assignin('base',"disparity",disparity);
                    t = app.createTransmission(disparity, framePath);
                    app.PreviewImage.ImageSource = app.createOutputPreview(inp,t);
                    
                case "Depth"
                    if app.SkyMask.Value ~= "none"
                        depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                        app.PreviewImage.ImageSource = app.createDepthPreview(depthPath,framePath);
                    else
                        app.PreviewImage.ImageSource = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"img", framePath+".png");

                        
                    end
                    
                case "Transmission"
                    depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                    disparity = app.createDisparity(depthPath,framePath);
                    app.PreviewImage.ImageSource = im2uint8(app.createTransmission(disparity,framePath));
                    
                otherwise
                    app.PreviewImage.ImageSource = fullfile(app.previewPath,"image_02","data", framePath+".png");
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.kittiPath = fullfile(fileparts(mfilename('fullpath')),'../KITTI/train');
            if ~isfolder(app.kittiPath)
                app.selectKittiPath();
            else
                app.updateKittiPath();
            end
%             disp(app.kittiPath)
            app.HazeGeneratorUIFigure.Visible = 'off';
            
            app.HazeGeneratorUIFigure.Visible = 'on';
            
        end

        % Clicked callback: FolderSelectToggle
        function FolderSelectToggleClicked(app, event)
            app.selectKittiPath();
        end

        % Value changed function: SequenceDropDown
        function SequenceDropDownValueChanged(app, event)
            app.updatePreviewFolder();
        end

        % Callback function
        function PreviewTypeOldSelectionChanged(app, event)
            app.updatePreview();
        end

        % Value changing function: FrameSpinner
        function FrameSpinnerValueChanging(app, event)
            changingValue = event.Value;
%             disp(changingValue)
            if changingValue==-1
                app.FrameSpinner.Value = app.FrameSpinner.Limits(2)-1;
%                 disp(app.FrameSpinner.Value)
            elseif changingValue==app.FrameSpinner.Limits(2)
                app.FrameSpinner.Value = 0;
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            changingValue = event.Value;
%             disp(changingValue)
            if changingValue==-1
                app.FrameSpinner.Value = app.FrameSpinner.Limits(2)-1;
%                 disp(app.FrameSpinner.Value)
            elseif changingValue==app.FrameSpinner.Limits(2)
                app.FrameSpinner.Value = 0;
            end
            app.updatePreview()
        end

        % Value changed function: DepthMapType
        function DepthMapTypeValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: SkyMask
        function SkyMaskValueChanged(app, event)
            app.updatePreview();
        end

        % Button pushed function: AirlightButton
        function AirlightButtonPushed(app, event)
           c = uisetcolor(app.AirlightButton.BackgroundColor);
           drawnow;
           figure(app.HazeGeneratorUIFigure);
           app.AirlightButton.BackgroundColor = c;
           app.updatePreview();
        end

        % Value changed function: VisibleDistanceEditField
        function VisibleDistanceEditFieldValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: DoubleMaskButton
        function DoubleMaskButtonValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: DebugButton
        function DebugButtonValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: PreviewDropDown
        function PreviewDropDownValueChanged(app, event)
            app.updatePreview();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.createVideo();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HazeGeneratorUIFigure = uifigure('Visible', 'off');
            app.HazeGeneratorUIFigure.Name = 'Haze Generator';
            app.Toolbar = uitoolbar(app.HazeGeneratorUIFigure);
            app.FolderSelectToggle = uipushtool(app.Toolbar);
            app.FolderSelectToggle.Tooltip = {'Select KITTI path'};
            app.FolderSelectToggle.ClickedCallback = createCallbackFcn(app, @FolderSelectToggleClicked, true);
            app.PlayVideoToggle = uitoggletool(app.Toolbar);
            app.PlayVideoToggle.Tooltip = {'Toggle Preview Video'};
            app.PlayVideoToggle.Separator = 'on';
            app.AdvancedModeToggle = uitoggletool(app.Toolbar);
            app.AdvancedModeToggle.Tooltip = {'Toggle Advanced Mode'};
            app.AdvancedModeToggle.Separator = 'on';
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Separator = 'on';
            app.GridLayout = uigridlayout(app.HazeGeneratorUIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PreviewImage = uiimage(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.FrameSpinner = uispinner(app.GridLayout6);
            app.FrameSpinner.ValueChangingFcn = createCallbackFcn(app, @FrameSpinnerValueChanging, true);
            app.FrameSpinner.Limits = [-1 100];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinnerLabel = uilabel(app.GridLayout6);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.SequenceDropDown = uidropdown(app.GridLayout6);
            app.SequenceDropDown.Items = {};
            app.SequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @SequenceDropDownValueChanged, true);
            app.SequenceDropDown.Value = {};
            app.SequenceDropDownLabel = uilabel(app.GridLayout6);
            app.SequenceDropDownLabel.Text = 'Sequence';
            app.PreviewDropDown = uidropdown(app.GridLayout6);
            app.PreviewDropDown.Items = {'Input', 'Output', 'Depth', 'Transmission'};
            app.PreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewDropDownValueChanged, true);
            app.PreviewDropDown.Value = 'Output';
            app.PreviewDropDownLabel = uilabel(app.GridLayout6);
            app.PreviewDropDownLabel.Text = 'Preview';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SkyMask = uidropdown(app.GridLayout4);
            app.SkyMask.Items = {'Standard', 'None'};
            app.SkyMask.ItemsData = {'default_refined', 'none', ''};
            app.SkyMask.ValueChangedFcn = createCallbackFcn(app, @SkyMaskValueChanged, true);
            app.SkyMask.Value = 'default_refined';
            app.SkyMaskLabel = uilabel(app.GridLayout4);
            app.SkyMaskLabel.Text = 'Sky Mask';
            app.DepthMapType = uidropdown(app.GridLayout4);
            app.DepthMapType.Items = {'MonoDepth2', 'ManyDepth', 'LEAStereo'};
            app.DepthMapType.ItemsData = {'unguided/monodepth', 'unguided/manydepth', 'leastereo'};
            app.DepthMapType.ValueChangedFcn = createCallbackFcn(app, @DepthMapTypeValueChanged, true);
            app.DepthMapType.Value = 'unguided/manydepth';
            app.DepthMapLabel = uilabel(app.GridLayout4);
            app.DepthMapLabel.Text = 'Depth Map';
            app.AirlightColourLabel = uilabel(app.GridLayout4);
            app.AirlightColourLabel.Text = 'Airlight Colour';
            app.AirlightButton = uibutton(app.GridLayout4, 'push');
            app.AirlightButton.ButtonPushedFcn = createCallbackFcn(app, @AirlightButtonPushed, true);
            app.AirlightButton.Text = '';
            app.VisibleDistanceEditFieldLabel = uilabel(app.GridLayout4);
            app.VisibleDistanceEditFieldLabel.Text = 'Visible Distance';
            app.DoubleMaskButton = uibutton(app.GridLayout4, 'state');
            app.DoubleMaskButton.ValueChangedFcn = createCallbackFcn(app, @DoubleMaskButtonValueChanged, true);
            app.DoubleMaskButton.Text = 'Double Mask';
            app.DoubleMaskButton.Value = true;
            app.DebugButton = uibutton(app.GridLayout4, 'state');
            app.DebugButton.ValueChangedFcn = createCallbackFcn(app, @DebugButtonValueChanged, true);
            app.DebugButton.Text = 'Debug';
            app.VisibleDistanceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.VisibleDistanceEditField.Limits = [0.0001 1];
            app.VisibleDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @VisibleDistanceEditFieldValueChanged, true);
            app.VisibleDistanceEditField.Value = 0.4;
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.HazeGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hazegenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HazeGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HazeGeneratorUIFigure)
        end
    end
end